classdef InferencePaperApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NetworkParametersandGroundTruthTab  matlab.ui.container.Tab
        ROCUnknown                      matlab.ui.control.UIAxes
        TruePrecisionDistributions      matlab.ui.control.UIAxes
        Heatmap                         matlab.ui.control.UIAxes
        ROCKnown                        matlab.ui.control.UIAxes
        SynapticMagnitudeSliderLabel    matlab.ui.control.Label
        SynapticMagnitudeSlider         matlab.ui.control.Slider
        SynapticVariabilitySliderLabel  matlab.ui.control.Label
        SynapticVariabilitySlider       matlab.ui.control.Slider
        RandomGraphModelDropDownLabel   matlab.ui.control.Label
        RandomGraphModelDropDown        matlab.ui.control.DropDown
        GenerateButton                  matlab.ui.control.Button
        NumberofNeuronsEditFieldLabel   matlab.ui.control.Label
        NumberofNeuronsEditField        matlab.ui.control.NumericEditField
        ConnectionProbabilitySliderLabel  matlab.ui.control.Label
        ConnectionProbabilitySlider     matlab.ui.control.Slider
        ExcitatoryRatioSliderLabel      matlab.ui.control.Label
        ExcitatoryRatioSlider           matlab.ui.control.Slider
        SynapticRatiosEditFieldLabel    matlab.ui.control.Label
        SynapticRatiosEditField         matlab.ui.control.EditField
        SynapticRatiosEditField_2       matlab.ui.control.EditField
        SynapticRatiosEditField_3       matlab.ui.control.EditField
        SynapticRatiosEditFieldLabel_2  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_3  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_4  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_5  matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        AnalysisTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Network = zeros(app.NumberofNeuronsEditField.Value); % Description
    end
    
    methods (Access = private)
        
        function Network = GenerateERNetwork(app)
            N = app.NumberofNeuronsEditField.Value;
            Ne = round(N*app.ExcitatoryRatio.Value);
            Ni = N - Ne;
            
            Jm = SimPar.J / sqrt(N);
            Vm = SimPar.V / N;
            sm = sqrt(Vm);
            
            P = app.ConnectionProbabilitySlider.Value * ones(2);
            
            if (sm(1,1) ~= 0) % use first element to decide
                Jmee_dist = makedist('normal',Jm(1,1),sm(1,1));
                Jmee_tdist = truncate(Jmee_dist,0,Inf); % Dale's Law
                Jmei_dist = makedist('normal',Jm(1,2),sm(1,2));
                Jmei_tdist = truncate(Jmei_dist,-Inf,0); % Dale's Law
                Jmie_dist = makedist('normal',Jm(2,1),sm(2,1));
                Jmie_tdist = truncate(Jmie_dist,0,Inf); % Dale's Law
                Jmii_dist = makedist('normal',Jm(2,2),sm(2,2));
                Jmii_tdist = truncate(Jmii_dist,-Inf,0); % Dale's Law
        
                J = [sparse(random(Jmee_tdist,[Ne Ne]).*binornd(1,P(1,1),Ne,Ne)) ...
                     sparse(random(Jmei_tdist,[Ne Ni]).*binornd(1,P(1,2),Ne,Ni)); ...
                     sparse(random(Jmie_tdist,[Ni Ne]).*binornd(1,P(2,1),Ni,Ne)) ...
                     sparse(random(Jmii_tdist,[Ni Ni]).*binornd(1,P(2,2),Ni,Ni))];
            else
                J = [Jm(1,1) * binornd(1,P(1,1),Ne,Ne) ...
                     Jm(1,2) * binornd(1,P(1,2),Ne,Ni); ...
                     Jm(2,1) * binornd(1,P(2,1),Ni,Ne) ...
                     Jm(2,2) * binornd(1,P(2,2),Ni,Ni)];
            end
            
            J(1:(N+1):end) = 0; % Remove self-loops
            
            Networks = struct();
            Networks.J = J;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            switch app.RandomGraphModelDropDown.Value
                case 'Erdos-Renyi'
                    Network = GenerateERNetwork(app.NumberofNeuronsEditField.Value,app.ConnectionProbabilitySlider.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NetworkParametersandGroundTruthTab = uitab(app.TabGroup);
            app.NetworkParametersandGroundTruthTab.Title = 'Network Parameters and Ground Truth';
            app.ROCUnknown = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.ROCUnknown, 'ROC - Unknown Cell Types')
            xlabel(app.ROCUnknown, 'X')
            ylabel(app.ROCUnknown, 'Y')
            app.TruePrecisionDistributions = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.TruePrecisionDistributions, 'True Precision Distributions')
            xlabel(app.TruePrecisionDistributions, 'Precision')
            ylabel(app.TruePrecisionDistributions, 'Density')
            app.Heatmap = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.Heatmap, 'Heatmap of Connectivity')
            xlabel(app.Heatmap, 'Neuron Index')
            ylabel(app.Heatmap, 'Neuron Index')
            app.ROCKnown = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.ROCKnown, 'ROC - Known Cell Types')
            xlabel(app.ROCKnown, 'X')
            ylabel(app.ROCKnown, 'Y')
            app.SynapticMagnitudeSliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticMagnitudeSliderLabel.Text = 'Synaptic Magnitude';
            app.SynapticMagnitudeSlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.SynapticVariabilitySliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticVariabilitySliderLabel.Text = 'Synaptic Variability';
            app.SynapticVariabilitySlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.RandomGraphModelDropDownLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.RandomGraphModelDropDownLabel.Text = 'Random Graph Model';
            app.RandomGraphModelDropDown = uidropdown(app.NetworkParametersandGroundTruthTab);
            app.RandomGraphModelDropDown.Items = {'Erdos-Renyi', 'Spatial'};
            app.RandomGraphModelDropDown.Value = 'Erdos-Renyi';
            app.GenerateButton = uibutton(app.NetworkParametersandGroundTruthTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.NumberofNeuronsEditFieldLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.NumberofNeuronsEditFieldLabel.Text = 'Number of Neurons';
            app.NumberofNeuronsEditField = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.NumberofNeuronsEditField.Value = 50;
            app.ConnectionProbabilitySliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.ConnectionProbabilitySliderLabel.Text = 'Connection Probability';
            app.ConnectionProbabilitySlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.ConnectionProbabilitySlider.Limits = [0 1];
            app.ConnectionProbabilitySlider.Value = 0.2;
            app.ExcitatoryRatioSliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.ExcitatoryRatioSliderLabel.Text = 'Excitatory Ratio';
            app.ExcitatoryRatioSlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.ExcitatoryRatioSlider.Limits = [0 1];
            app.ExcitatoryRatioSlider.Value = 0.8;
            app.SynapticRatiosEditFieldLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel.Text = 'Synaptic Ratios';
            app.SynapticRatiosEditField = uieditfield(app.NetworkParametersandGroundTruthTab, 'text');
            app.SynapticRatiosEditField_2 = uieditfield(app.NetworkParametersandGroundTruthTab, 'text');
            app.SynapticRatiosEditField_3 = uieditfield(app.NetworkParametersandGroundTruthTab, 'text');
            app.SynapticRatiosEditFieldLabel_2 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_2.Text = 'e';
            app.SynapticRatiosEditFieldLabel_3 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_3.Text = 'i';
            app.SynapticRatiosEditFieldLabel_4 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_4.Text = 'e';
            app.SynapticRatiosEditFieldLabel_5 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_5.Text = 'i';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InferencePaperApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end